classdef App_EDOPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AM2MNumEDOPVIUIFigure     matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        AcercadaAppMenu           matlab.ui.container.Menu
        AutorMenu                 matlab.ui.container.Menu
        ComoutilizaraaplicaoMenu  matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        ApresentardadosButton     matlab.ui.control.Button
        DadosdoPVIPanel           matlab.ui.container.Panel
        y0EditField               matlab.ui.control.NumericEditField
        y0EditFieldLabel          matlab.ui.control.Label
        nEditField                matlab.ui.control.NumericEditField
        nEditFieldLabel           matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        ftyEditField              matlab.ui.control.EditField
        ftyEditFieldLabel         matlab.ui.control.Label
        MtodosButtonGroup         matlab.ui.container.ButtonGroup
        EulerMelhoradoButton      matlab.ui.control.RadioButton
        todosButton               matlab.ui.control.RadioButton
        outroButton               matlab.ui.control.RadioButton
        ode45Button               matlab.ui.control.RadioButton
        RK4Button                 matlab.ui.control.RadioButton
        RK2Button                 matlab.ui.control.RadioButton
        EulerButton               matlab.ui.control.RadioButton
        RightPanel                matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        SoluoExataLabel           matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %variáveis globais para ter um controlo 
        % enquanto se preenchem os campos
        f % função a utilizar
        a = 0 %t inicial
        b = 0 %t final
        n = 0 %n subintervalos
        y0 = 0 %y inicial
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %colocar os valores pré-preenchidos nas var. globais
            
            value = app.ftyEditField.Value;
            strF=value;
            app.f=@(t,y) eval(vectorize(strF)); %function handle de f
            app.a = app.aEditField.Value;
            app.b = app.bEditField.Value;
            app.n = app.nEditField.Value;
            app.y0 = app.y0EditField.Value;
        end

        % Button pushed function: ApresentardadosButton
        function ApresentardadosButtonPushed(app, event)
            %Ação a realizar quando é clicado botão Apresentar dados

            h=(app.b-app.a)/app.n; %calcula o passo (h)
            t=app.a:h:app.b; %preenche t com os valores iniciais e finais com subintervalos de tamanho h
            try %testa a função
                syms y(t) %var simbólica 
                symF = app.f(t,y); %f(t,y) já tem func. handle
                
                eqn = diff(y,t) == symF; %coloca o diferencial de f em eqn
                ci = y(0) == app.y0; %coloca y0 em ci
                
                sExata = dsolve(eqn,ci); %resolve com exata
                app.SoluoExataLabel.Text="Solução Exata: "+string(sExata);
                yExata = @(t)eval(vectorize(sExata)); %handle da exata
                t = app.a:((app.b-app.a)/app.n):app.b; 
                yExata = yExata(t);
            catch me
                errordlg('Confirme a função f por favor','ERRO')
                errordlg(me.message);
                return
            end

            switch app.MtodosButtonGroup.SelectedObject.Text %selecionar a função a usar

                case 'Euler' %euler
                    cla(app.UIAxes) %limpa o gráfico antes de o preencher
                    try
                        yMetodo=NEuler(app.f,app.a,app.b,app.n,app.y0); %reconfirma se função funciona em euler
                    catch 
                        warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                        warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                        return
                    end
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t,yExata,'b--o') %aplica gráfico da exata
                    plot(app.UIAxes, t,yMetodo,'r*')%aplica gráfico da euler
                    hold(app.UIAxes,'off')
                    legend(app.UIAxes,'Exata','Método de Euler');
                    
                    erroMetodo=abs(yExata-yMetodo); %calcula o erro

                    app.UITable.Data= table(t', yExata', yMetodo', erroMetodo'); %preenche tabela com exata, euler e erro
                    app.UITable.ColumnName = {'t' 'Exata' 'Euler' 'Erro'};
                    
               
                case 'EulerMelhorado' %euler melhorado (HEUN)
                    cla(app.UIAxes)%limpa o gráfico antes de o preencher
                    try
                        yMetodo=NHeun(app.f,app.a,app.b,app.n,app.y0);%reconfirma se função funciona em heun
                    catch 
                        warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                        warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                        return
                    end
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t,yExata,'b--o')%aplica gráfico da exata
                    plot(app.UIAxes, t,yMetodo,'r*')%aplica gráfico da euler mel (heun)
                    hold(app.UIAxes,'off')
                    legend(app.UIAxes,'Exata','Método de Euler Melhorado (Heun)');
                    erroMetodo=abs(yExata-yMetodo);%calcula o erro

                    app.UITable.Data= table(t', yExata', yMetodo', erroMetodo');%preenche tabela com exata, heun e erro
                    app.UITable.ColumnName = {'t' 'Exata' 'Euler Melhorado (Heun)' 'Erro'};
                
                
                case 'ode45' %ODE45 MATLAB
                    cla(app.UIAxes)
                    try
                        yMetodo=N_ODE45(app.f,app.a,app.b,app.n,app.y0);%reconfirma se função funciona em ode45
                    catch 
                        warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                        warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                        return
                    end
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t,yExata,'b--o')%aplica gráfico da exata
                    plot(app.UIAxes, t,yMetodo,'r*')%aplica gráfico da ode45
                    hold(app.UIAxes,'off');
                    legend(app.UIAxes,'Exata','Método ODE45 MATLAB');

                    erroMetodo=abs(yExata-yMetodo);%calcula o erro

                    app.UITable.Data= table(t', yExata', yMetodo', erroMetodo');%preenche tabela com exata, ode45 e erro
                    app.UITable.ColumnName = {'t' 'Exata' 'ODE45' 'Erro'};
            
                
                case 'RK2' %RK2
                    cla(app.UIAxes)
                    try
                        yMetodo=NRK2(app.f,app.a,app.b,app.n,app.y0);%reconfirma se função funciona em RK2
                    catch 
                        warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                        warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                        return
                    end
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t,yExata,'b--o')%aplica gráfico da exata
                    plot(app.UIAxes, t,yMetodo,'r*')%aplica gráfico da RK2
                    hold(app.UIAxes,'off')
                    legend(app.UIAxes,'Exata','Método Runge-Kutta Ordem 2');
                    
                    erroMetodo=abs(yExata-yMetodo);%calcula o erro

                    app.UITable.Data= table(t', yExata', yMetodo', erroMetodo');%preenche tabela com exata, RK2 e erro
                    app.UITable.ColumnName = {'t' 'Exata' 'RK2' 'Erro'};


                case 'RK4' %RK4
                    cla(app.UIAxes)
                    try
                        yMetodo=NRK4(app.f,app.a,app.b,app.n,app.y0);%reconfirma se função funciona em RK4
                    catch 
                        warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                        warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                        return
                    end
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t,yExata,'b--o')%aplica gráfico da exata
                    plot(app.UIAxes, t,yMetodo,'r*')%aplica gráfico da RK4
                    hold(app.UIAxes,'off')
                    legend(app.UIAxes,'Exata','Método Runge-Kutta Ordem 4');

                    erroMetodo=abs(yExata-yMetodo);%calcula o erro

                    app.UITable.Data= table(t', yExata', yMetodo', erroMetodo');%preenche tabela com exata, RK4 e erro
                    app.UITable.ColumnName = {'t' 'Exata' 'RK4' 'Erro'};
                
                
                case 'outro' %Adams-Bashford
                    cla(app.UIAxes)
                    try
                        yMetodo=NAdamsBashford(app.f,app.a,app.b,app.n,app.y0); %reconfirma se função funciona em Adams-Moulton
                    catch 
                        warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                        warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                        return
                    end
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t,yExata,'b--o')%aplica gráfico da exata
                    plot(app.UIAxes, t,yMetodo,'r*')%aplica gráfico da Adams-Moulton
                    hold(app.UIAxes,'off')
                    legend(app.UIAxes,'Exata','Método Adams-Bashford');

                    erroMetodo=abs(yExata-yMetodo);%calcula o erro


                    app.UITable.Data= table(t', yExata', yMetodo', erroMetodo');%preenche tabela com exata, Adams-Moulton e erro
                    app.UITable.ColumnName = {'t' 'Exata' 'Adams-Bashford' 'Erro'};

                case 'todos' %o mesmo em todos
                    cla(app.UIAxes)
                    try
                        %confirma todos e os seus erros
                        yEuler = NEuler(app.f,app.a,app.b,app.n,app.y0);%ex.: função
                        yErEu = abs(yExata-yEuler); %erro da função
                        yRK2 = NRK2(app.f,app.a,app.b,app.n,app.y0);
                        yErRK2 = abs(yExata-yRK2);
                        yRK4 = NRK4(app.f,app.a,app.b,app.n,app.y0);
                        yErRK4 = abs(yExata-yRK4);
                        yode45 = N_ODE45(app.f,app.a,app.b,app.n,app.y0);
                        yErode = abs(yExata-yode45);
                        yMelhorado = NHeun(app.f,app.a,app.b,app.n,app.y0);
                        yErMel = abs(yExata-yMelhorado);
                        yAdams=NAdamsBashford(app.f,app.a,app.b,app.n,app.y0);
                        yErAdams = abs(yExata-yAdams);
                    catch 
                        warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                        warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                        return
                    end
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, t,yExata,'b-o')%aplica gráfico da exata
                    plot(app.UIAxes, t,yEuler,'r*')%aplica gráfico da euler
                    plot(app.UIAxes, t,yRK2,'go')%aplica gráfico da RK2
                    plot(app.UIAxes, t,yRK4,'c')%aplica gráfico da RK4
                    plot(app.UIAxes, t,yode45,'m')%aplica gráfico da ODE45
                    plot(app.UIAxes, t,yMelhorado,'y')%aplica gráfico da Euler Melhorada (Heun)
                    plot(app.UIAxes, t,yAdams,'k')%aplica gráfico da Adams-Moulton
                    hold(app.UIAxes,'off')

                    %preenche a tabela com as funções e os diversos erros
                    app.UITable.Data= table(t', yExata', yEuler', yErEu', yRK2',yErRK2', yRK4', yErRK4',yode45',yErode', yMelhorado',yErMel', yAdams',yErAdams');
                    app.UITable.ColumnName = {'t' 'Exata' 'Euler' 'Erro Euler' 'RK2' 'Erro RK2' 'RK4' 'Erro RK4' 'ODE45' 'Erro ODE45' 'Euler Melhorado (Heun)' 'Erro Euler Melhorado (Heun)' 'Adams-Moulton' 'Erro Adams-Moulton'};
                                      
                    legend(app.UIAxes,'Exata','Método Euler','Método RK2','Método RK4','Método ODE45','Método E.Melhorado','Método Adams-Bashford');
            end           
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            %apanha o valor em a e coloca na var. global sempre
            %que o campo é editado
            value = app.aEditField.Value; 
            app.a=value;
        end

        % Value changed function: ftyEditField
        function ftyEditFieldValueChanged(app, event)
            %apanha a função f(t,y) sempre que o campo muda
            % e confirma se realmente é uma função ao testar um método (Euler)
            % com a mesma
            value = app.ftyEditField.Value;
            strF=value;
            app.f=@(t,y) eval(vectorize(strF));

            try
                NEuler(app.f,0,5,10,5); %teste de função
            catch
                warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                return
            end

        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            %apanha b sempre que muda no campo e verifica se é maior que a            
            value = app.bEditField.Value;
            if(value<app.a)
                app.bEditField.Value = app.b;
                warndlg('b tem que ser maior que a','ERRO: b<a');
            else
                app.b=value;
            end
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            %apanha o nº de subintervalos sempre que muda no campo
            %valor não pode ser menor que 0, então é confirmado aqui
            value = app.nEditField.Value;
            if(value<=0)
                app.nEditField.Value = app.n;
                warndlg('n tem que ser maior que 0','ERRO: b<0');
            else
                app.n=value;
            end
        end

        % Value changed function: y0EditField
        function y0EditFieldValueChanged(app, event)
            %apanha y(0) e coloca-o na var global
            value = app.y0EditField.Value;
            app.y0 = value;
        end

        % Menu selected function: AcercadaAppMenu
        function AcercadaAppMenuSelected(app, event)
            %Menu-> Acerca desta App: indica ao utilizador do que se trata
            %a app através de uma janela de mensagem
            msgbox('Este software tem o objetivo de calcular um EDO/PVI através de métodos numéricos (Euler, RK2, ...), apresentando um gráfico e uma tabela com os resultados.' ,'Acerca da APP');
        end

        % Menu selected function: AutorMenu
        function AutorMenuSelected(app, event)
            %apresenta o autor com imagem do mesmo
            [icondata,iconmap]=imread("perfil.ico");
            msgbox(["Trabalho realizado no âmbito da Unidade Curricular de","", "Análise Matemática II - Ano-Letivo 2022/23",...
                "","LEI-RPL ISEC | Renato Alexandre Oliveira Craveiro | 2018011392 "], ...
         "Autor","custom",icondata,iconmap);
        end

        % Menu selected function: ComoutilizaraaplicaoMenu
        function ComoutilizaraaplicaoMenuSelected(app, event)
            %contém instruções do uso da aplicação
            msgbox(["Preencher função (em t e y) no campo f","", "a = t inicial | b = t final | n = nº de subintervalos | y(0) = condição inicial",...
                "","Escolher o método a utilizar (Euler, RK2, ...)","","Clicar no botão 'Apresentar dados'","","Os resultados aparecem no gráfico e tabela apresentadas no lado direito"], ...
                "Como utilizar a aplicação");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AM2MNumEDOPVIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {299, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AM2MNumEDOPVIUIFigure = uifigure('Visible', 'off');
            app.AM2MNumEDOPVIUIFigure.Name = 'AM2 - M. Num. EDO/PVI';
            app.AM2MNumEDOPVIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.AM2MNumEDOPVIUIFigure);
            app.Menu.Text = 'Menu';
            app.AcercadaAppMenu = uimenu(app.Menu);
            app.AcercadaAppMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadaAppMenuSelected, true);
            app.AcercadaAppMenu.Text = 'Acerca da App';
            app.AutorMenu = uimenu(app.Menu);
            app.AutorMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorMenuSelected, true);
            app.AutorMenu.Text = 'Autor';
            app.ComoutilizaraaplicaoMenu = uimenu(app.Menu);
            app.ComoutilizaraaplicaoMenu.MenuSelectedFcn = createCallbackFcn(app, @ComoutilizaraaplicaoMenuSelected, true);
            app.ComoutilizaraaplicaoMenu.Text = 'Como utilizar a aplicação';
            app.GridLayout = uigridlayout(app.AM2MNumEDOPVIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.MtodosButtonGroup = uibuttongroup(app.GridLayout3);
            app.MtodosButtonGroup.Title = 'Métodos';
            app.EulerButton = uiradiobutton(app.MtodosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uiradiobutton(app.MtodosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uiradiobutton(app.MtodosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.ode45Button = uiradiobutton(app.MtodosButtonGroup);
            app.ode45Button.Text = 'ode45';
            app.outroButton = uiradiobutton(app.MtodosButtonGroup);
            app.outroButton.Text = 'outro';
            app.todosButton = uiradiobutton(app.MtodosButtonGroup);
            app.todosButton.Text = 'todos';
            app.EulerMelhoradoButton = uiradiobutton(app.MtodosButtonGroup);
            app.EulerMelhoradoButton.Text = 'EulerMelhorado';
            app.DadosdoPVIPanel = uipanel(app.GridLayout3);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.ftyEditField = uieditfield(app.DadosdoPVIPanel, 'text');
            app.ftyEditField.ValueChangedFcn = createCallbackFcn(app, @ftyEditFieldValueChanged, true);
            app.ftyEditField.Value = 't+y';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 5;
            app.nEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 10;
            app.y0EditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.y0EditFieldLabel.Text = 'y(0)';
            app.y0EditField = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.y0EditField.ValueChangedFcn = createCallbackFcn(app, @y0EditFieldValueChanged, true);
            app.y0EditField.Value = 5;
            app.ApresentardadosButton = uibutton(app.GridLayout3, 'push');
            app.ApresentardadosButton.ButtonPushedFcn = createCallbackFcn(app, @ApresentardadosButtonPushed, true);
            app.ApresentardadosButton.Text = 'Apresentar dados';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Gráfico Final')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Selecione um Método para começar'};
            app.UITable.RowName = {};
            app.SoluoExataLabel = uilabel(app.GridLayout2);
            app.SoluoExataLabel.Text = 'Solução Exata: [sem f(t,y)]';
            % Show the figure after all components are created
            app.AM2MNumEDOPVIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_EDOPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AM2MNumEDOPVIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AM2MNumEDOPVIUIFigure)
        end
    end
end