classdef App_SED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AM2SEDUIFigure                 matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        AcercadaAppMenu                matlab.ui.container.Menu
        AutorMenu                      matlab.ui.container.Menu
        ComoutilizaraaplicaoMenu       matlab.ui.container.Menu
        PredefiniesMenu                matlab.ui.container.Menu
        ProblemadoPnduloMenu           matlab.ui.container.Menu
        ProblemadaMolaMassasemAmortecimentoMenu  matlab.ui.container.Menu
        ProblemadaMolaMassacomAmortecimentoMenu  matlab.ui.container.Menu
        ProblemadoCircuitoEltricoMenu  matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        CalcularButton                 matlab.ui.control.Button
        DadosPanel                     matlab.ui.container.Panel
        predfLabel                     matlab.ui.control.Label
        vgtuvEditField                 matlab.ui.control.EditField
        vgtuvEditFieldLabel            matlab.ui.control.Label
        uftuvEditField                 matlab.ui.control.EditField
        uftuvLabel                     matlab.ui.control.Label
        v0EditField                    matlab.ui.control.NumericEditField
        v0EditFieldLabel               matlab.ui.control.Label
        u0EditField                    matlab.ui.control.NumericEditField
        u0EditFieldLabel               matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        MtodosButtonGroup              matlab.ui.container.ButtonGroup
        todosButton                    matlab.ui.control.RadioButton
        RK4Button                      matlab.ui.control.RadioButton
        RK2Button                      matlab.ui.control.RadioButton
        EulerButton                    matlab.ui.control.RadioButton
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %variáveis globais para ter um controlo 
        % enquanto se preenchem os campos
        g % função a utilizar
        a = 0 %t inicial
        b = 0 %t final
        n = 0 %n subintervalos
        y0 = 0 %y inicial
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %colocar os valores pré-preenchidos nas var. globais
            
            value = app.vgtuvEditField.Value;
            strG=value;
            app.g=@(t,y) eval(vectorize(strG)); %function handle de g
            app.a = app.aEditField.Value;
            app.b = app.bEditField.Value;
            app.n = app.nEditField.Value;
            app.y0 = app.u0EditField.Value;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %Ação a realizar quando é clicado botão Apresentar dados
            exataflag=0;
            syms u(t) v(t);
            strG= app.vgtuvEditField.Value;
            f =@(t,u,v) v;
            g =@(t,u,v) eval(vectorize(strG));
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            n = app.nEditField.Value;
            u0 = app.u0EditField.Value;
            v0 = app.v0EditField.Value;
                        
            try
                eqns = [diff(u,t)==v,diff(v,t)==eval(vectorize(strG))];
                ci = [u(0)==u0,v(0)==v0];
                [sExataU,sExataV] = dsolve(eqns,ci);
    
    
                h =@(t) eval(vectorize(char(sExataU)));
    
                t=a:(b-a)/n:b;
                uExata = h(t);
                exataflag=1;
            catch me
                errordlg("Equação não linear! Sem solução exata!","Erro S. Exata");
            end

        switch app.MtodosButtonGroup.SelectedObject.Text %selecionar a função a usar
            case 'Euler' %Método de Euler
                [t,u,v]= NEulerSED(f,g,a,b,n,u0,v0);
    
                if exataflag==1
                    try
                    Erro = abs(uExata-u);
                    app.UITable.Data = array2table([t.',u.',uExata.',Erro.'],"VariableNames",{'t','uEuler','uexata','Erro'});
                    app.UITable.ColumnName = {'t' 'uEuler' 'uExata' 'Erro'};
                    catch me
                        exataflag=0;
                        app.UITable.Data = array2table([t.',u.'],"VariableNames",{'t','u'});
                        app.UITable.ColumnName = {'t' 'uEuler'};
                        errordlg("Equação não linear! Sem solução exata!","Erro S. Exata");
    
                    end
                else
                    app.UITable.Data = array2table([t.',u.'],"VariableNames",{'t','uEuler'});
                    app.UITable.ColumnName = {'t' 'uEuler'};
                end
    
                plot(app.UIAxes, t,u,'-r*')
                hold(app.UIAxes,'on')
                if(exataflag==1)
                    plot(app.UIAxes,t,uExata);
                end
                hold(app.UIAxes,'off')
                legend(app.UIAxes, "uEuler","uExata")
            
            case 'RK2'
                [t,u,v]= NRK2SED(f,g,a,b,n,u0,v0);
    
                if exataflag==1
                    try
                    Erro = abs(uExata-u);
                    app.UITable.Data = array2table([t.',u.',uExata.',Erro.'],"VariableNames",{'t','uRK2','uexata','Erro'});
                    app.UITable.ColumnName = {'t' 'uRK2' 'uExata' 'Erro'};
                    catch me
                        exataflag=0;
                        app.UITable.Data = array2table([t.',u.'],"VariableNames",{'t','uRK2'});
                        app.UITable.ColumnName = {'t' 'uRK2'};
                        errordlg("Equação não linear! Sem solução exata!","Erro S. Exata");
    
                    end
                else
                    app.UITable.Data = array2table([t.',u.'],"VariableNames",{'t','uRK2'});
                    app.UITable.ColumnName = {'t' 'uRK2'};
                end
    
                plot(app.UIAxes, t,u,'-r*')
                hold(app.UIAxes,'on')
                if(exataflag==1)
                    plot(app.UIAxes,t,uExata);
                end
                hold(app.UIAxes,'off')
                legend(app.UIAxes, "uRK4","uExata")

            case 'RK4'
                [t,u,v]= NRK4SED(f,g,a,b,n,u0,v0);
    
                if exataflag==1
                    try
                    Erro = abs(uExata-u);
                    app.UITable.Data = array2table([t.',u.',uExata.',Erro.'],"VariableNames",{'t','uRK4','uexata','Erro'});
                    app.UITable.ColumnName = {'t' 'uRK4' 'uExata' 'Erro'};
                    catch me
                        exataflag=0;
                        app.UITable.Data = array2table([t.',u.'],"VariableNames",{'t','uRK4'});
                        app.UITable.ColumnName = {'t' 'uRK4'};
                        errordlg("Equação não linear! Sem solução exata!","Erro S. Exata");
    
                    end
                else
                    app.UITable.Data = array2table([t.',u.'],"VariableNames",{'t','uRK4'});
                    app.UITable.ColumnName = {'t' 'uRK4'};
                end
    
                plot(app.UIAxes, t,u,'-r*')
                hold(app.UIAxes,'on')
                if(exataflag==1)
                    plot(app.UIAxes,t,uExata);
                end
                hold(app.UIAxes,'off')
                legend(app.UIAxes, "uRK4","uExata")
            case 'todos'
                [tRK4,uRK4,vRK4]= NRK4SED(f,g,a,b,n,u0,v0);
                [tRK2,uRK2,vRK2]= NRK2SED(f,g,a,b,n,u0,v0);
                [tEuler,uEuler,vEuler]= NEulerSED(f,g,a,b,n,u0,v0);
                if exataflag==1
                    try
                        ErE = abs(uExata-uEuler);
                        ErRK2 = abs(uExata-uRK2);
                        ErRK4 = abs(uExata-uRK4);
                        app.UITable.Data = array2table([t.',uEuler.',ErE.', uRK2.',ErRK2.', uRK4.',ErRK4.', uExata.'],"VariableNames",{'t','uEuler','Erro Euler','uRK2','Erro RK2','uRK4','Erro RK4','uexata'});
                        app.UITable.ColumnName = {'t' 'uEuler' 'Erro Euler' 'uRK2' 'Erro RK2' 'uRK4' 'Erro RK4' 'uExata'};
                    catch me
                        exataflag=0;
                        app.UITable.Data = array2table([t.',uEuler.', uRK2.',uRK4.'],"VariableNames",{'t','uEuler','uRK2','uRK4'});
                        app.UITable.ColumnName = {'t' 'uEuler' 'uRK2' 'uRK4'};
                        errordlg("Equação não linear! Sem solução exata!","Erro S. Exata");
    
                    end
                else
                    app.UITable.Data = array2table([t.',uEuler.', uRK2.',uRK4.',uExata.'],"VariableNames",{'t','uEuler','uRK2','uRK4'});
                    app.UITable.ColumnName = {'t' 'uEuler' 'uRK2' 'uRK4'};
                    app.UITable.ColumnName = {'t' 'uRK4'};
                end
    
                plot(app.UIAxes, t,uEuler,'-r*')
                hold(app.UIAxes,'on')
                plot(app.UIAxes, t,uRK2,'-go')
                plot(app.UIAxes, t,uRK4,'-c+')
                if(exataflag==1)
                    plot(app.UIAxes,t,uExata,'-kx');
                end
                hold(app.UIAxes,'off')
                legend(app.UIAxes, "uEuler","uRK2","uRK4","uExata")
                

        end       
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            %apanha o valor em a e coloca na var. global sempre
            %que o campo é editado
            value = app.aEditField.Value; 
            app.a=value;
        end

        % Callback function
        function ftyEditFieldValueChanged(app, event)
            %apanha a função f(t,y) sempre que o campo muda
            % e confirma se realmente é uma função ao testar um método (Euler)
            % com a mesma
            value = app.ftyEditField.Value;
            strF=value;
            app.g=@(t,y) eval(vectorize(strF));

            try
                NEuler(app.g,0,5,10,5); %teste de função
            catch
                warning('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!');
                warndlg('Função escrita incorretamente. Verifique se está a escrever uma função em t e y ( f(t,y) )!','ERRO: Função incorretamente escrita!')
                return
            end

        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            %apanha b sempre que muda no campo e verifica se é maior que a            
            value = app.bEditField.Value;
            if(value<app.a)
                app.bEditField.Value = app.b;
                warndlg('b tem que ser maior que a','ERRO: b<a');
            else
                app.b=value;
            end
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            %apanha o nº de subintervalos sempre que muda no campo
            %valor não pode ser menor que 0, então é confirmado aqui
            value = app.nEditField.Value;
            if(value<=0)
                app.nEditField.Value = app.n;
                warndlg('n tem que ser maior que 0','ERRO: b<0');
            else
                app.n=value;
            end
        end

        % Value changed function: u0EditField
        function u0EditFieldValueChanged(app, event)
            %apanha y(0) e coloca-o na var global
            value = app.u0EditField.Value;
            app.y0 = value;
        end

        % Menu selected function: AcercadaAppMenu
        function AcercadaAppMenuSelected(app, event)
            %Menu-> Acerca desta App: indica ao utilizador do que se trata
            %a app através de uma janela de mensagem
            msgbox('Este software tem o objetivo de calcular um SEDO através de métodos numéricos (Euler, RK2, ...), apresentando um gráfico e uma tabela com os resultados.' ,'Acerca da APP');
        end

        % Menu selected function: AutorMenu
        function AutorMenuSelected(app, event)
            %apresenta o autor com imagem do mesmo
            [icondata,iconmap]=imread("perfil.ico");
            msgbox(["Trabalho realizado no âmbito da Unidade Curricular de","", "Análise Matemática II - Ano-Letivo 2022/23",...
                "","LEI-RPL ISEC | Renato Alexandre Oliveira Craveiro | 2018011392 "], ...
         "Autor","custom",icondata,iconmap);
        end

        % Menu selected function: ComoutilizaraaplicaoMenu
        function ComoutilizaraaplicaoMenuSelected(app, event)
            %contém instruções do uso da aplicação
            msgbox(["Preencher função (em u e v) no campo v'=g(t,u,v)","", "a = t inicial | b = t final | n = nº de subintervalos | u(0)/v(0) = condiçõeso iniciais",...
                "","Escolher o método a utilizar (Euler, RK2, ...)","","Clicar no botão 'Calcular'","","Os resultados aparecem no gráfico e tabela apresentadas no lado direito"], ...
                "Como utilizar a aplicação");
        end

        % Menu selected function: ProblemadoPnduloMenu
        function ProblemadoPnduloMenuSelected(app, event)
            app.predfLabel.Text='[Última predifinição escolhida] Pêndulo';
            
            app.aEditField.Value =0;
            app.bEditField.Value =15;
            app.nEditField.Value =100;
            app.u0EditField.Value =pi/2;
            app.v0EditField.Value=0;
            app.vgtuvEditField.Value='-sin(u)-0.3*v';
        end

        % Callback function
        function ButtonPushed(app, event)
            syms u(t) v(t)

            f =@(t,u,v) v;
            g =@(t,u,v) -16*u;
            a = 0;
            b = 4;
            n = 100;
            u0 = 9;
            v0 = 0;
            
            strG=app.vgtuvEditField.Value;


            eqns = [diff(u,t)==v,diff(v,t)==eval(vectorize(strG))];
            ci = [u(0)==u0,v(0)==v0];
            [sExataU,sExataV] = dsolve(eqns,ci);


            h =@(t) eval(vectorize(char(sExataU)));

            t=a:(b-a)/n:b;
            uExata = h(t);
            
            plot(app.UIAxes,t,uExata,"-g+")

        end

        % Menu selected function: ProblemadaMolaMassasemAmortecimentoMenu
        function ProblemadaMolaMassasemAmortecimentoMenuSelected(app, event)
            app.predfLabel.Text='[Última predifinição escolhida] Mola-Massa (sem Amortecimento)';
            
            app.aEditField.Value =0;
            app.bEditField.Value =4;
            app.nEditField.Value =100;
            app.u0EditField.Value =9;
            app.v0EditField.Value=0;
            app.vgtuvEditField.Value='-16*u';
        end

        % Menu selected function: ProblemadaMolaMassacomAmortecimentoMenu
        function ProblemadaMolaMassacomAmortecimentoMenuSelected(app, event)
            app.predfLabel.Text='[Última predifinição escolhida] Mola-Massa (com Amortecimento)';

            app.aEditField.Value =0;
            app.bEditField.Value =15;
            app.nEditField.Value =100;
            app.u0EditField.Value =0;
            app.v0EditField.Value=0;
            app.vgtuvEditField.Value='cos(t)-20*u-10*v';

        end

        % Menu selected function: ProblemadoCircuitoEltricoMenu
        function ProblemadoCircuitoEltricoMenuSelected(app, event)
            app.predfLabel.Text='[Última predifinição escolhida] Circuito Elétrico';

            app.aEditField.Value =0;
            app.bEditField.Value =15;
            app.nEditField.Value =100;
            app.u0EditField.Value =0;
            app.v0EditField.Value=0;
            app.vgtuvEditField.Value='cos(t)/5-u/15-2*v';

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AM2SEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {299, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AM2SEDUIFigure = uifigure('Visible', 'off');
            app.AM2SEDUIFigure.Name = 'AM2 - Sistemas Equações Diferenciais';
            app.AM2SEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.AM2SEDUIFigure);
            app.Menu.Text = 'Menu';
            app.AcercadaAppMenu = uimenu(app.Menu);
            app.AcercadaAppMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadaAppMenuSelected, true);
            app.AcercadaAppMenu.Text = 'Acerca da App';
            app.AutorMenu = uimenu(app.Menu);
            app.AutorMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorMenuSelected, true);
            app.AutorMenu.Text = 'Autor';
            app.ComoutilizaraaplicaoMenu = uimenu(app.Menu);
            app.ComoutilizaraaplicaoMenu.MenuSelectedFcn = createCallbackFcn(app, @ComoutilizaraaplicaoMenuSelected, true);
            app.ComoutilizaraaplicaoMenu.Text = 'Como utilizar a aplicação';
            app.PredefiniesMenu = uimenu(app.AM2SEDUIFigure);
            app.PredefiniesMenu.Text = 'Predefinições';
            app.ProblemadoPnduloMenu = uimenu(app.PredefiniesMenu);
            app.ProblemadoPnduloMenu.MenuSelectedFcn = createCallbackFcn(app, @ProblemadoPnduloMenuSelected, true);
            app.ProblemadoPnduloMenu.Text = 'Problema do Pêndulo';
            app.ProblemadaMolaMassasemAmortecimentoMenu = uimenu(app.PredefiniesMenu);
            app.ProblemadaMolaMassasemAmortecimentoMenu.MenuSelectedFcn = createCallbackFcn(app, @ProblemadaMolaMassasemAmortecimentoMenuSelected, true);
            app.ProblemadaMolaMassasemAmortecimentoMenu.Text = 'Problema da Mola-Massa sem Amortecimento';
            app.ProblemadaMolaMassacomAmortecimentoMenu = uimenu(app.PredefiniesMenu);
            app.ProblemadaMolaMassacomAmortecimentoMenu.MenuSelectedFcn = createCallbackFcn(app, @ProblemadaMolaMassacomAmortecimentoMenuSelected, true);
            app.ProblemadaMolaMassacomAmortecimentoMenu.Text = 'Problema da Mola-Massa com Amortecimento';
            app.ProblemadoCircuitoEltricoMenu = uimenu(app.PredefiniesMenu);
            app.ProblemadoCircuitoEltricoMenu.MenuSelectedFcn = createCallbackFcn(app, @ProblemadoCircuitoEltricoMenuSelected, true);
            app.ProblemadoCircuitoEltricoMenu.Text = 'Problema do Circuito Elétrico';
            app.GridLayout = uigridlayout(app.AM2SEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.MtodosButtonGroup = uibuttongroup(app.GridLayout3);
            app.MtodosButtonGroup.Title = 'Métodos';
            app.EulerButton = uiradiobutton(app.MtodosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uiradiobutton(app.MtodosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uiradiobutton(app.MtodosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.todosButton = uiradiobutton(app.MtodosButtonGroup);
            app.todosButton.Text = 'todos';
            app.DadosPanel = uipanel(app.GridLayout3);
            app.DadosPanel.Title = 'Dados';
            app.aEditFieldLabel = uilabel(app.DadosPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.DadosPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.DadosPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.DadosPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 5;
            app.nEditFieldLabel = uilabel(app.DadosPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.DadosPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 10;
            app.u0EditFieldLabel = uilabel(app.DadosPanel);
            app.u0EditFieldLabel.Text = 'u(0)';
            app.u0EditField = uieditfield(app.DadosPanel, 'numeric');
            app.u0EditField.ValueChangedFcn = createCallbackFcn(app, @u0EditFieldValueChanged, true);
            app.u0EditField.Value = 5;
            app.v0EditFieldLabel = uilabel(app.DadosPanel);
            app.v0EditFieldLabel.Text = 'v(0)';
            app.v0EditField = uieditfield(app.DadosPanel, 'numeric');
            app.v0EditField.Value = 5;
            app.uftuvLabel = uilabel(app.DadosPanel);
            app.uftuvLabel.Text = 'u''=f(t,u,v)';
            app.uftuvEditField = uieditfield(app.DadosPanel, 'text');
            app.uftuvEditField.Editable = 'off';
            app.uftuvEditField.Enable = 'off';
            app.uftuvEditField.Value = 'v';
            app.vgtuvEditFieldLabel = uilabel(app.DadosPanel);
            app.vgtuvEditFieldLabel.Text = 'v''=g(t,u,v)';
            app.vgtuvEditField = uieditfield(app.DadosPanel, 'text');
            app.vgtuvEditField.Value = 't+u+v';
            app.predfLabel = uilabel(app.DadosPanel);
            app.predfLabel.Text = 'Insira dados ou escolha uma predefinição';
            app.CalcularButton = uibutton(app.GridLayout3, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Gráfico Final')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'u')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Selecione um Método para começar'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.AM2SEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_SED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AM2SEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AM2SEDUIFigure)
        end
    end
end