classdef MCDI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PredefiniesMenu                matlab.ui.container.Menu
        FR1VarRealMenu                 matlab.ui.container.Menu
        sinxMenu                       matlab.ui.container.Menu
        cosxMenu                       matlab.ui.container.Menu
        tanxMenu                       matlab.ui.container.Menu
        FR2VarReaisMenu                matlab.ui.container.Menu
        expxsinyMenu                   matlab.ui.container.Menu
        expxsinyMenu_2                 matlab.ui.container.Menu
        x2y2Menu                       matlab.ui.container.Menu
        x2y2Menu_2                     matlab.ui.container.Menu
        SobreMenu                      matlab.ui.container.Menu
        OAutorMenu                     matlab.ui.container.Menu
        AAplicaoMenu                   matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        FunorealdevarivelrealyfxTab    matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        DerivadasTab                   matlab.ui.container.Tab
        TipoDerivadasButtonGroup       matlab.ui.container.ButtonGroup
        SegundaDerivadaButton          matlab.ui.control.RadioButton
        DifFinitasRegressivas2ptsButton  matlab.ui.control.RadioButton
        DifFinitasCentradas3ptsButton  matlab.ui.control.RadioButton
        DifFinitasProgressivas3ptsButton  matlab.ui.control.RadioButton
        DifFinitasRegressivas3ptsButton  matlab.ui.control.RadioButton
        DifFinitasProgressivas2ptsButton  matlab.ui.control.RadioButton
        PrimitivasTab                  matlab.ui.container.Tab
        TipoPrimitivasButtonGroup      matlab.ui.container.ButtonGroup
        SimpsonButton                  matlab.ui.control.RadioButton
        TrapziosButton                 matlab.ui.control.RadioButton
        GridLayout8                    matlab.ui.container.GridLayout
        DerivadaPrimitivaButtonGroup   matlab.ui.container.ButtonGroup
        PrimitivaButton                matlab.ui.control.RadioButton
        DerivadaButton                 matlab.ui.control.RadioButton
        lblDerivPrim                   matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        DadosdoproblemaPanel           matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        fxEditField                    matlab.ui.control.EditField
        fxEditFieldLabel               matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        nEditField                     matlab.ui.control.NumericEditField
        nLabel                         matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aLabel                         matlab.ui.control.Label
        Funorealde2variveisreaiszfxyTab  matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        GridLayout3_2                  matlab.ui.container.GridLayout
        UIAxes_2                       matlab.ui.control.UIAxes
        GridLayout2_2                  matlab.ui.container.GridLayout
        lblHarm                        matlab.ui.control.Label
        TipodeGrficoButtonGroup        matlab.ui.container.ButtonGroup
        CurvasdeNvelButton             matlab.ui.control.RadioButton
        GrficodaFunoButton             matlab.ui.control.RadioButton
        CalcularButton_2               matlab.ui.control.Button
        lblFunc                        matlab.ui.control.Label
        DadosdoproblemaPanel_2         matlab.ui.container.Panel
        GridLayout4_2                  matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        fxyEditField                   matlab.ui.control.EditField
        fxyEditFieldLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            f=@(x)eval(vectorize(app.fxEditField.Value));
            try
                fTeste=f(sym('x'));    
                syms x;
                a=app.aEditField.Value;
                b=app.bEditField.Value;
                n=app.nEditField.Value;
            catch me
                errordlg('Insira uma função em x (f(x) - ex.: sin(x)).', 'f(x) inválida');
                return;
            end

            if(a>b || n<=0)
                errordlg('Confira os valores de a, b e h.', 'Valores a, b ou h inválidos');
                return;
            end

            switch app.TipoDerivadasButtonGroup.SelectedObject.Text
                case 'Segunda Derivada'
                    derivadaExata = diff(diff(f(x)));
                otherwise
                    derivadaExata = diff(f(x));
            end


            switch app.DerivadaPrimitivaButtonGroup.SelectedObject.Text
                case 'Derivada'
                    switch app.TipoDerivadasButtonGroup.SelectedObject.Text
                    case 'Segunda Derivada'
                        dF = diff(diff(f(x)));
                    otherwise
                        dF = diff(f(x));
                    end
                    app.lblDerivPrim.Text = ['$' latex(dF) '$'];

                case 'Primitiva'
                    pF = int(f(x));
                    app.lblDerivPrim.Text = ['$' latex(pF) '+ c' '$'];
            end

            switch app.TabGroup2.SelectedTab.Title
                case 'Derivadas'
                    

                    switch app.TipoDerivadasButtonGroup.SelectedObject.Text
                        case 'Dif. Finitas Progressivas - 2 pts.'
                            [dydx,x,y] = DFP(f,a,b,n);
                        case 'Dif. Finitas Progressivas - 3 pts.'
                            [dydx,x,y] = DFP3(f,a,b,n);
                        case 'Dif. Finitas Regressivas - 2 pts.'
                            [dydx,x,y] = DFR2(f,a,b,n);
                        case 'Dif. Finitas Regressivas - 3 pts.'
                            [dydx,x,y] = DFR3(f,a,b,n);
                        case 'Dif. Finitas Centradas - 3 pts.'
                            [dydx,x,y] = DFC3(f,a,b,n);
                        case 'Segunda Derivada'
                            [dydx,x,y] = DF2D3(f,a,b,n);
                    end

                    derivadaExata = @(x)eval(vectorize(derivadaExata));
                    yderivadaExata =derivadaExata(x);

                    strDerivada='';
                    strDerLeg='';

                    switch app.TipoDerivadasButtonGroup.SelectedObject.Text
                        case 'Dif. Finitas Progressivas - 2 pts.'
                            strDerivada='DFP 2 pontos';
                            strDerLeg="DFP 2 pontos comparada a Derivada Exata";

                        case 'Dif. Finitas Progressivas - 3 pts.'
                            strDerivada='DFP 3 pontos';
                            strDerLeg="DFP 3 pontos comparada a Derivada Exata";

                        case 'Dif. Finitas Regressivas - 2 pts.'
                            strDerivada='DFR 2 pontos';
                            strDerLeg="DFR 2 pontos comparada a Derivada Exata";

                        case 'Dif. Finitas Regressivas - 3 pts.'
                            strDerivada='DFR 3 pontos';
                            strDerLeg="DFR 3 pontos comparada a Derivada Exata";

                        case 'Dif. Finitas Centradas - 3 pts.'
                            strDerivada='DFC 3 pontos';
                            strDerLeg="DFC 3 pontos comparada a Derivada Exata";
                        case 'Segunda Derivada'
                            strDerivada='Segunda Derivada em 3 pontos';
                            strDerLeg="Segunda Derivada em 3 pontos comparada a Derivada Exata";

                    end

                    erro = yderivadaExata - dydx;

                    app.UITable.Data = array2table([x.',y.',dydx.',yderivadaExata.',erro.'],"VariableNames",{'x','y',strDerivada,'Derivada Exata','erro'});
                    app.UITable.ColumnName = {'x','y',strDerivada,'y Derivada Exata','erro'};

                    plot(app.UIAxes,x,dydx,'-r*');
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,x,yderivadaExata,'b--o');
                    hold(app.UIAxes,'off');
                    legend(app.UIAxes,strDerivada,"y Derivada Exata");
                    app.UIAxes.Title.String =strDerLeg;


                case 'Primitivas'
                    h=(b-a)/n;
                    x=a:h:b;
                    y=f(x);
                    
                    xx=[x,x(end),x(1)];
                    yy=[y,0,0];
                    exata = double(int(f(sym('x')),a,b));
                    fEx = int(f(sym('x')));
                    switch app.TipoPrimitivasButtonGroup.SelectedObject.Text
                        case 'Simpson'
                            pF = CIRS(f,a,b,n);
                            strPrimitiva='Regra de Simpson';
                            strPrLeg='Integral resolvido com Regra de Simpson';

                            plot(app.UIAxes,pF,'linewidth',10);
                            hold(app.UIAxes,'on');
                            fill(app.UIAxes,xx,yy,[0, 0.4470, 0.7410]);
                            %set(handles.eQ, 'String', yTrapezio);

                            grid(app.UIAxes, 'on');
                            % xlabel('x')
                            % ylabel('y')
                            hold(app.UIAxes,'off');

                        case 'Trapézios'
                            pF=CIRT(f,a,b,n);
                            strPrimitiva='Regra dos Trapézios';
                            strPrLeg='Integral resolvido com Regra dos Trapézios';

                            plot(app.UIAxes,pF,'linewidth',10);
                            hold(app.UIAxes,'on');
                            fill(app.UIAxes,xx,yy,[0.9290 0.6940 0.1250]);
                            %set(handles.eQ, 'String', yTrapezio);

                            grid(app.UIAxes, 'on');
                            %plot(app.UIAxes,f(x),[a:b]);
                            %fill(app.UIAxes,xx,yy,'g');
                            % xlabel('x')
                            % ylabel('y')
                            hold(app.UIAxes,'off');
                    end
                    
                    
                    erro=exata - pF;


                    aPF = [pF];
                    aExata = [exata];
                    aErro = [erro];
                    app.UITable.Data  = array2table([aPF.', aExata.', aErro.'],'VariableNames',{strPrimitiva,'Solução Exata','erro'});
                    app.UITable.ColumnName = {strPrimitiva,'Solução Exata','erro'};

                    app.UIAxes.Title.String =strPrLeg;

            end


        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            f=@(x,y)eval(vectorize(app.fxyEditField.Value));
            try
                fTeste=f(sym('x'),sym('y'));    

            catch me
                errordlg('Insira uma função em x e y (f(x,y) - ex.: x^2-y^2).', 'f(x,y) inválida');
                return;
            end

            func= sym(f);


            switch app.TipodeGrficoButtonGroup.SelectedObject.Text
                case 'Gráfico da Função'
                    fsurf(app.UIAxes_2,f);
                case 'Curvas de Nível'
                    fcontour(app.UIAxes_2,f);
            end
            app.lblFunc.Text = ['$z =f(x,y) =' latex(func) '$'];
            l = laplacian(func);

            if(l==0)
                app.lblHarm.Text="A Função é Harmónica (Laplaciano = 0).";
                app.lblHarm.BackgroundColor = "g";
                app.lblHarm.FontColor = "k";
            else
                app.lblHarm.Text = ['Função não é Harmonica (Laplaciano = $' latex(l) '$)!'];
                app.lblHarm.BackgroundColor = "r";
                app.lblHarm.FontColor ="w";
            end
        end

        % Menu selected function: OAutorMenu
        function OAutorMenuSelected(app, event)
            [icondata,iconmap]=imread("perfil.ico");
            msgbox(["Trabalho realizado no âmbito da Unidade Curricular de","", "Análise Matemática II - Ano-Letivo 2022/23",...
                "","LEI-RPL ISEC | Renato Alexandre Oliveira Craveiro | 2018011392 "], ...
         "Autor","custom",icondata,iconmap);
        end

        % Menu selected function: AAplicaoMenu
        function AAplicaoMenuSelected(app, event)
            msgbox('Aplicação pretende efetuar a reoslução de Derivadas/Aproximações e Primitivas/Aproximações das mesmas. Também apresenta gráficos 3D de funções/curvas de nível de 2 variáveis, indicando se são harmónicas (ou não) e o seu Laplaciano)','Acerca da Aplicação');
        end

        % Menu selected function: sinxMenu
        function sinxMenuSelected(app, event)
            app.fxEditField.Value='sin(x)';
            app.aEditField.Value=0;
            app.bEditField.Value=10;
            app.nEditField.Value=100;
        end

        % Menu selected function: cosxMenu
        function cosxMenuSelected(app, event)
            app.fxEditField.Value='cos(x)';
            app.aEditField.Value=0;
            app.bEditField.Value=10;
            app.nEditField.Value=100;
        end

        % Menu selected function: tanxMenu
        function tanxMenuSelected(app, event)
            app.fxEditField.Value='tan(x)';
            app.aEditField.Value=0;
            app.bEditField.Value=10;
            app.nEditField.Value=100;
        end

        % Menu selected function: expxsinyMenu
        function expxsinyMenuSelected(app, event)
            app.fxyEditField.Value='exp(x)*sin(y)';
        end

        % Menu selected function: expxsinyMenu_2
        function expxsinyMenu_2Selected(app, event)
            app.fxyEditField.Value='exp(x)+sin(y)';
        end

        % Menu selected function: x2y2Menu
        function x2y2MenuSelected(app, event)
            app.fxyEditField.Value='x^2-y^2';
        end

        % Menu selected function: x2y2Menu_2
        function x2y2Menu_2Selected(app, event)
            app.fxyEditField.Value='x^2+y^2';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredefiniesMenu = uimenu(app.UIFigure);
            app.PredefiniesMenu.Text = 'Predefinições';
            app.FR1VarRealMenu = uimenu(app.PredefiniesMenu);
            app.FR1VarRealMenu.Text = 'FR 1 Var. Real';
            app.sinxMenu = uimenu(app.FR1VarRealMenu);
            app.sinxMenu.MenuSelectedFcn = createCallbackFcn(app, @sinxMenuSelected, true);
            app.sinxMenu.Text = 'sin(x)';
            app.cosxMenu = uimenu(app.FR1VarRealMenu);
            app.cosxMenu.MenuSelectedFcn = createCallbackFcn(app, @cosxMenuSelected, true);
            app.cosxMenu.Text = 'cos(x)';
            app.tanxMenu = uimenu(app.FR1VarRealMenu);
            app.tanxMenu.MenuSelectedFcn = createCallbackFcn(app, @tanxMenuSelected, true);
            app.tanxMenu.Text = 'tan(x)';
            app.FR2VarReaisMenu = uimenu(app.PredefiniesMenu);
            app.FR2VarReaisMenu.Text = 'FR 2 Var. Reais';
            app.expxsinyMenu = uimenu(app.FR2VarReaisMenu);
            app.expxsinyMenu.MenuSelectedFcn = createCallbackFcn(app, @expxsinyMenuSelected, true);
            app.expxsinyMenu.Text = 'exp(x)*sin(y)';
            app.expxsinyMenu_2 = uimenu(app.FR2VarReaisMenu);
            app.expxsinyMenu_2.MenuSelectedFcn = createCallbackFcn(app, @expxsinyMenu_2Selected, true);
            app.expxsinyMenu_2.Text = 'exp(x)+sin(y)';
            app.x2y2Menu = uimenu(app.FR2VarReaisMenu);
            app.x2y2Menu.MenuSelectedFcn = createCallbackFcn(app, @x2y2MenuSelected, true);
            app.x2y2Menu.Text = 'x^2-y^2';
            app.x2y2Menu_2 = uimenu(app.FR2VarReaisMenu);
            app.x2y2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @x2y2Menu_2Selected, true);
            app.x2y2Menu_2.Text = 'x^2+y^2';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.OAutorMenu = uimenu(app.SobreMenu);
            app.OAutorMenu.MenuSelectedFcn = createCallbackFcn(app, @OAutorMenuSelected, true);
            app.OAutorMenu.Text = 'O Autor';
            app.AAplicaoMenu = uimenu(app.SobreMenu);
            app.AAplicaoMenu.MenuSelectedFcn = createCallbackFcn(app, @AAplicaoMenuSelected, true);
            app.AAplicaoMenu.Text = 'A Aplicação';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FunorealdevarivelrealyfxTab = uitab(app.TabGroup);
            app.FunorealdevarivelrealyfxTab.Title = 'Função real de variável real y = f(x)';
            app.GridLayout = uigridlayout(app.FunorealdevarivelrealyfxTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DadosdoproblemaPanel = uipanel(app.GridLayout2);
            app.DadosdoproblemaPanel.Title = 'Dados do problema';
            app.GridLayout4 = uigridlayout(app.DadosdoproblemaPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.aLabel = uilabel(app.GridLayout5);
            app.aLabel.Text = 'a =';
            app.aEditField = uieditfield(app.GridLayout5, 'numeric');
            app.bLabel = uilabel(app.GridLayout5);
            app.bLabel.Text = 'b =';
            app.bEditField = uieditfield(app.GridLayout5, 'numeric');
            app.bEditField.Value = 10;
            app.nLabel = uilabel(app.GridLayout5);
            app.nLabel.Text = 'n =';
            app.nEditField = uieditfield(app.GridLayout5, 'numeric');
            app.nEditField.Value = 100;
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.fxEditFieldLabel = uilabel(app.GridLayout6);
            app.fxEditFieldLabel.Text = 'f(x)=';
            app.fxEditField = uieditfield(app.GridLayout6, 'text');
            app.fxEditField.Value = 'sin(x)';
            app.CalcularButton = uibutton(app.GridLayout2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.lblDerivPrim = uilabel(app.GridLayout8);
            app.lblDerivPrim.Interpreter = 'latex';
            app.lblDerivPrim.Text = '[Resultado aparece aqui]';
            app.DerivadaPrimitivaButtonGroup = uibuttongroup(app.GridLayout8);
            app.DerivadaPrimitivaButtonGroup.Title = 'Derivada/Primitiva';
            app.DerivadaButton = uiradiobutton(app.DerivadaPrimitivaButtonGroup);
            app.DerivadaButton.Text = 'Derivada';
            app.DerivadaButton.Value = true;
            app.PrimitivaButton = uiradiobutton(app.DerivadaPrimitivaButtonGroup);
            app.PrimitivaButton.Text = 'Primitiva';
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.DerivadasTab = uitab(app.TabGroup2);
            app.DerivadasTab.Title = 'Derivadas';
            app.TipoDerivadasButtonGroup = uibuttongroup(app.DerivadasTab);
            app.TipoDerivadasButtonGroup.Title = 'Tipo Derivadas';
            app.DifFinitasProgressivas2ptsButton = uiradiobutton(app.TipoDerivadasButtonGroup);
            app.DifFinitasProgressivas2ptsButton.Text = 'Dif. Finitas Progressivas - 2 pts.';
            app.DifFinitasProgressivas2ptsButton.Value = true;
            app.DifFinitasRegressivas3ptsButton = uiradiobutton(app.TipoDerivadasButtonGroup);
            app.DifFinitasRegressivas3ptsButton.Text = 'Dif. Finitas Regressivas - 3 pts.';
            app.DifFinitasProgressivas3ptsButton = uiradiobutton(app.TipoDerivadasButtonGroup);
            app.DifFinitasProgressivas3ptsButton.Text = 'Dif. Finitas Progressivas - 3 pts.';
            app.DifFinitasCentradas3ptsButton = uiradiobutton(app.TipoDerivadasButtonGroup);
            app.DifFinitasCentradas3ptsButton.Text = 'Dif. Finitas Centradas - 3 pts.';
            app.DifFinitasRegressivas2ptsButton = uiradiobutton(app.TipoDerivadasButtonGroup);
            app.DifFinitasRegressivas2ptsButton.Text = 'Dif. Finitas Regressivas - 2 pts.';
            app.SegundaDerivadaButton = uiradiobutton(app.TipoDerivadasButtonGroup);
            app.SegundaDerivadaButton.Text = 'Segunda Derivada';
            app.PrimitivasTab = uitab(app.TabGroup2);
            app.PrimitivasTab.Title = 'Primitivas';
            app.TipoPrimitivasButtonGroup = uibuttongroup(app.PrimitivasTab);
            app.TipoPrimitivasButtonGroup.Title = 'Tipo Primitivas';
            app.TrapziosButton = uiradiobutton(app.TipoPrimitivasButtonGroup);
            app.TrapziosButton.Text = 'Trapézios';
            app.TrapziosButton.Value = true;
            app.SimpsonButton = uiradiobutton(app.TipoPrimitivasButtonGroup);
            app.SimpsonButton.Text = 'Simpson';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Funorealde2variveisreaiszfxyTab = uitab(app.TabGroup);
            app.Funorealde2variveisreaiszfxyTab.Title = 'Função real de 2 variáveis reais z= f(x,y)';
            app.GridLayout_2 = uigridlayout(app.Funorealde2variveisreaiszfxyTab);
            app.GridLayout2_2 = uigridlayout(app.GridLayout_2);
            app.DadosdoproblemaPanel_2 = uipanel(app.GridLayout2_2);
            app.DadosdoproblemaPanel_2.Title = 'Dados do problema';
            app.GridLayout4_2 = uigridlayout(app.DadosdoproblemaPanel_2);
            app.GridLayout10 = uigridlayout(app.GridLayout4_2);
            app.fxyEditFieldLabel = uilabel(app.GridLayout10);
            app.fxyEditFieldLabel.Text = 'f(x,y)=';
            app.fxyEditField = uieditfield(app.GridLayout10, 'text');
            app.fxyEditField.Value = 'exp(x)*sin(y)';
            app.lblFunc = uilabel(app.GridLayout2_2);
            app.lblFunc.Interpreter = 'latex';
            app.lblFunc.Text = '';
            app.CalcularButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.TipodeGrficoButtonGroup = uibuttongroup(app.GridLayout2_2);
            app.TipodeGrficoButtonGroup.Title = 'Tipo de Gráfico';
            app.GrficodaFunoButton = uiradiobutton(app.TipodeGrficoButtonGroup);
            app.GrficodaFunoButton.Text = 'Gráfico da Função';
            app.GrficodaFunoButton.Value = true;
            app.CurvasdeNvelButton = uiradiobutton(app.TipodeGrficoButtonGroup);
            app.CurvasdeNvelButton.Text = 'Curvas de Nível';
            app.lblHarm = uilabel(app.GridLayout2_2);
            app.lblHarm.Interpreter = 'latex';
            app.lblHarm.Text = '';
            app.GridLayout3_2 = uigridlayout(app.GridLayout_2);
            app.UIAxes_2 = uiaxes(app.GridLayout3_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCDI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end